classdef Thermal_Aware_planner_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermalAwareAppUIFigure  matlab.ui.Figure
        PlanningTimeValueLabel   matlab.ui.control.Label
        TimeResultLabel          matlab.ui.control.Label
        NodesValueLabel          matlab.ui.control.Label
        NodesResultLabel         matlab.ui.control.Label
        ResultsPanel             matlab.ui.container.Panel
        TotalCostValueLabel      matlab.ui.control.Label
        TotalCostResultabel      matlab.ui.control.Label
        ThermalCostValueLabel    matlab.ui.control.Label
        ThermalCostResultLabel   matlab.ui.control.Label
        DistanceValueLabel       matlab.ui.control.Label
        DistanceResultLabel      matlab.ui.control.Label
        PlannerPanel             matlab.ui.container.Panel
        PlanningStatusLabel      matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        PlanOptimalPathButton    matlab.ui.control.Button
        ThermalWeightEditField   matlab.ui.control.NumericEditField
        ThermalWeightLabel       matlab.ui.control.Label
        DistanceWeightEditField  matlab.ui.control.NumericEditField
        DistanceWeightLabel      matlab.ui.control.Label
        CostFunctionLabel        matlab.ui.control.Label
        VisualizationPanel       matlab.ui.container.Panel
        UIAxes3D                 matlab.ui.control.UIAxes
        LoadMapButton            matlab.ui.control.Button
        GoalZFieldEditField      matlab.ui.control.NumericEditField
        GoalYFieldEditField      matlab.ui.control.NumericEditField
        GoalZPoint               matlab.ui.control.Label
        GoalYPoint               matlab.ui.control.Label
        GoalXPoint               matlab.ui.control.Label
        GoalXFieldEditField      matlab.ui.control.NumericEditField
        GoalPointLabel           matlab.ui.control.Label
        StartZFieldEditField     matlab.ui.control.NumericEditField
        StartYFieldEditField     matlab.ui.control.NumericEditField
        StartZLabel              matlab.ui.control.Label
        StartYLabel              matlab.ui.control.Label
        StartXLabel              matlab.ui.control.Label
        StartXFieldEditField     matlab.ui.control.NumericEditField
        StartPointLabel          matlab.ui.control.Label
        EnvironmentPanel         matlab.ui.container.Panel
        SystemStatus             matlab.ui.control.Label
        ProjectSubtitle          matlab.ui.control.Label
        HeaderPanel              matlab.ui.container.Panel
        ProjectTitle             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ThermalMap % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
 % Load thermal map
[file, path] = uigetfile('*.csv', 'Select Thermal Map');

if isequal(file, 0)
    return;
end

fullFileName = fullfile(path, file);

try
    app.ThermalMap = readmatrix(fullFileName);

    % Check that the file contains data
    if isempty(app.ThermalMap)
        uialert(app.UIFigure, ...
            'Thermal map file is empty.', ...
            'Loading Error');
        return;
    end

    % Check that data is numeric
    if ~isnumeric(app.ThermalMap)
        uialert(app.UIFigure, ...
            'Thermal map contains invalid data.', ...
            'Loading Error');
        return;
    end

    % Display thermal map
    imagesc(app.UIAxes3D, app.ThermalMap);
    colorbar(app.UIAxes3D);

    title(app.UIAxes3D, 'THERMAL MAP');
    xlabel(app.UIAxes3D, 'X [m]');
    ylabel(app.UIAxes3D, 'Y [m]');

    axis(app.UIAxes3D, 'tight');

    % Status
    app.SystemStatus.Text = 'THERMAL MAP LOADED';

catch ME

    uialert(app.UIFigure, ...
        ['Failed to load thermal map: ' ME.message], ...
        'Loading Error');

    app.SystemStatus.Text = 'LOAD ERROR';

end

        end

        % Button pushed function: PlanOptimalPathButton
        function PlanOptimalPathButtonPushed(app, event)
  % Button pushed function: PlanOptimalPathButton

    try

        %% Check thermal map
        if isempty(app.ThermalMap)

            uialert(app.ThermalAwareAppUIFigure, ...
                'Please load the thermal map first.', ...
                'Planning Error');

            app.SystemStatus.Text = 'NO THERMAL MAP';

            return;
        end


        %% Read Start Point
        startX = app.StartXFieldEditField.Value;
        startY = app.StartYFieldEditField.Value;
        startZ = app.StartZFieldEditField.Value;


        %% Read Goal Point
        goalX = app.GoalXFieldEditField.Value;
        goalY = app.GoalYFieldEditField.Value;
        goalZ = app.GoalZFieldEditField.Value;


        %% Read Cost Weights
        distanceWeight = app.DistanceWeightEditField.Value;
        thermalWeight = app.ThermalWeightEditField.Value;


        %% Validate weights

        if distanceWeight < 0 || thermalWeight < 0

            uialert(app.ThermalAwareAppUIFigure, ...
                'Weights must be zero or positive.', ...
                'Planning Error');

            app.SystemStatus.Text = 'INVALID WEIGHTS';

            return;
        end


        %% Validate Start and Goal coordinates

        if any(isnan([startX startY startZ goalX goalY goalZ]))

            uialert(app.ThermalAwareAppUIFigure, ...
                'Start and goal coordinates must be valid numbers.', ...
                'Planning Error');

            app.SystemStatus.Text = 'INVALID COORDINATES';

            return;
        end


        %% Convert coordinates to map indices

      %% Convert physical coordinates to map indices

[rows, cols] = size(app.ThermalMap);

% Physical map limits
xMin = 0.5;
xMax = 4.5;

yMin = 0;
yMax = 35;

% Check physical coordinates
if startX < xMin || startX > xMax || ...
   startY < yMin || startY > yMax  ||...
   goalX < xMin || goalX > xMax || ...
   goalY < yMin || goalY > yMax

    uialert(app.ThermalAwareAppUIFigure, ...
        'Start or goal point is outside the thermal map.', ...
        'Planning Error');

    app.SystemStatus.Text = 'INVALID POINT';

    return;
end

% Convert X coordinate to column
startCol = round( ...
    1 + (startX - xMin) / (xMax - xMin) * (cols - 1));

goalCol = round( ...
    1 + (goalX - xMin) / (xMax - xMin) * (cols - 1));

% Convert Y coordinate to row
startRow = round( ...
    1 + (startY - yMin) / (yMax - yMin) * (rows - 1));

goalRow = round( ...
    1 + (goalY - yMin) / (yMax - yMin) * (rows - 1));

        %% Get map dimensions

        [rows, cols] = size(app.ThermalMap);


        %% Check boundaries

        if startRow < 1 || startRow > rows ||   ...
           startCol < 1 ||  startCol > cols||   ...
           goalRow < 1 || goalRow > rows  || ...
           goalCol < 1 || goalCol > cols

            uialert(app.ThermalAwareAppUIFigure, ...
                'Start or goal point is outside the thermal map.', ...
                'Planning Error');

            app.SystemStatus.Text = 'INVALID POINT';

            return;
        end


        %% Update status

        app.SystemStatus.Text = 'PLANNING PATH...';

        drawnow;


        %% Start planning timer

        planningTimer = tic;


        %% Run A* algorithm

        [path, totalDistance, thermalCost, nodesExpanded] = ...
            localAStar( ...
            app.ThermalMap, ...
            [startRow startCol], ...
            [goalRow goalCol], ...
            distanceWeight, ...
            thermalWeight);


        %% Calculate planning time

        planningTime = toc(planningTimer);


        %% Check whether path was found

        if isempty(path)

            uialert(app.ThermalAwareAppUIFigure, ...
                'No valid path could be found between the start and goal points.', ...
                'Planning Error');

            app.SystemStatus.Text = 'NO PATH FOUND';

            return;
        end


        %% Calculate total cost

        totalCost = ...
            distanceWeight * totalDistance + ...
            thermalWeight * thermalCost;


        %% Display path on thermal map

        hold(app.UIAxes3D, 'on');

        plot(app.UIAxes3D, ...
            path(:,2), ...
            path(:,1), ...
            'r-', ...
            'LineWidth', 3);

        % Start point
        plot(app.UIAxes3D, ...
            startCol, ...
            startRow, ...
            'go', ...
            'MarkerSize', 10, ...
            'LineWidth', 2, ...
            'MarkerFaceColor', 'g');

        % Goal point
        plot(app.UIAxes3D, ...
            goalCol, ...
            goalRow, ...
            'mo', ...
            'MarkerSize', 10, ...
            'LineWidth', 2, ...
            'MarkerFaceColor', 'm');

        hold(app.UIAxes3D, 'off');


        %% Update Planning Results
        app.DistanceResultLabel.Text = ...
            sprintf('%.2f m', totalDistance);


        app.ThermalCostResultLabel.Text = ...
            sprintf('%.2f', thermalCost);


     %%   app.TotalCostResultLabel.Text = ...
         %%   sprintf('%.2f', totalCost);


        app.NodesResultLabel.Text = ...
            sprintf('%d', nodesExpanded);


        app.PlanningTimeValueLabel.Text = ...
            sprintf('%.4f s', planningTime);


        %% Update system status

        app.SystemStatus.Text = ...
            'PATH PLANNED SUCCESSFULLY';


    catch ME

        %% Error handling

        uialert(app.ThermalAwareAppUIFigure, ...
            ME.message, ...
            'Planning Error');

        app.SystemStatus.Text = ...
            'PLANNING ERROR';

    end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clear thermal map
app.ThermalMap = [];

% Clear planned path
app.PlanOptimalPathButton = [];

% Clear visualization
cla(app.UIAxes3D);

% Reset results
app.DistanceResultLabel.Text = '--';
app.ThermalCostResultLabel.Text = '--';
app.TotalCostResultLabel.Text = '--';
app.NodesResultLabel.Text = '--';
app.PlanningTimeValueLabel.Text = '--';

% Reset status
app.SystemStatus.Text = 'READY TO PLAN';

% Reset points
app.StartXFieldEditField.Value = 0;
app.StartYFieldEditField.Value = 0;
app.StartZFieldEditField.Value = 0;

app.GoalXFieldEditField.Value = 10;
app.GoalYFieldEditField.Value = 10;
app.GoalZFieldEditField.Value = 0;

% Reset weights
app.DistanceWeightEditField.Value = 1;
app.ThermalWeightEditField.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermalAwareAppUIFigure = uifigure('Visible', 'off');
            app.ThermalAwareAppUIFigure.Name = 'ThermalAwareApp';
            app.HeaderPanel = uipanel(app.ThermalAwareAppUIFigure);
            app.ProjectTitle = uilabel(app.HeaderPanel);
            app.ProjectTitle.Text = 'Thermal-Aware Path Planner';
            app.ProjectSubtitle = uilabel(app.ThermalAwareAppUIFigure);
            app.ProjectSubtitle.Text = 'Intelligent  Path Planning Based on Distance and Thermall Cost ';
            app.SystemStatus = uilabel(app.ThermalAwareAppUIFigure);
            app.SystemStatus.Text = 'SYSTEM READY';
            app.EnvironmentPanel = uipanel(app.ThermalAwareAppUIFigure);
            app.EnvironmentPanel.Title = 'ENVIRONMENT';
            app.StartPointLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.StartPointLabel.Text = 'START POINT ';
            app.StartXFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.StartXFieldEditField.Value = 1;
            app.StartXLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.StartXLabel.Text = 'X';
            app.StartYLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.StartYLabel.Text = 'Y';
            app.StartZLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.StartZLabel.Text = 'Z';
            app.StartYFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.StartYFieldEditField.Value = 3;
            app.StartZFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.GoalPointLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.GoalPointLabel.Text = 'Goal POINT ';
            app.GoalXFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.GoalXFieldEditField.Value = 4;
            app.GoalXPoint = uilabel(app.ThermalAwareAppUIFigure);
            app.GoalXPoint.Text = 'X';
            app.GoalYPoint = uilabel(app.ThermalAwareAppUIFigure);
            app.GoalYPoint.Text = 'Y';
            app.GoalZPoint = uilabel(app.ThermalAwareAppUIFigure);
            app.GoalZPoint.Text = 'Z';
            app.GoalYFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.GoalYFieldEditField.Value = 30;
            app.GoalZFieldEditField = uieditfield(app.ThermalAwareAppUIFigure, 'numeric');
            app.LoadMapButton = uibutton(app.ThermalAwareAppUIFigure, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'LOAD THERMAL MAP';
            app.VisualizationPanel = uipanel(app.ThermalAwareAppUIFigure);
            app.VisualizationPanel.Title = 'THERMAL ENVIRONMEN';
            app.UIAxes3D = uiaxes(app.VisualizationPanel);
            title(app.UIAxes3D, 'THERMAL Map')
            xlabel(app.UIAxes3D, 'X [m]')
            ylabel(app.UIAxes3D, 'Y [m]')
            zlabel(app.UIAxes3D, 'Z [m]')
            app.PlannerPanel = uipanel(app.ThermalAwareAppUIFigure);
            app.PlannerPanel.Title = 'PATH PLANNER';
            app.CostFunctionLabel = uilabel(app.PlannerPanel);
            app.CostFunctionLabel.Text = 'COST FUNCTION ';
            app.DistanceWeightLabel = uilabel(app.PlannerPanel);
            app.DistanceWeightLabel.Text = 'DISTANCE WEIGHT ';
            app.DistanceWeightEditField = uieditfield(app.PlannerPanel, 'numeric');
            app.DistanceWeightEditField.Value = 1;
            app.ThermalWeightLabel = uilabel(app.PlannerPanel);
            app.ThermalWeightLabel.Text = 'THERMAL WEIGHT';
            app.ThermalWeightEditField = uieditfield(app.PlannerPanel, 'numeric');
            app.ThermalWeightEditField.Value = 1;
            app.PlanOptimalPathButton = uibutton(app.PlannerPanel, 'push');
            app.PlanOptimalPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanOptimalPathButtonPushed, true);
            app.PlanOptimalPathButton.Text = 'PLAN OPTIMAL PATH';
            app.ResetButton = uibutton(app.PlannerPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'RESET';
            app.PlanningStatusLabel = uilabel(app.PlannerPanel);
            app.PlanningStatusLabel.Text = 'READY TO PLAN';
            app.ResultsPanel = uipanel(app.ThermalAwareAppUIFigure);
            app.ResultsPanel.Title = 'PLANNING RESULTS';
            app.DistanceResultLabel = uilabel(app.ResultsPanel);
            app.DistanceResultLabel.Text = 'DISTANCE';
            app.DistanceValueLabel = uilabel(app.ResultsPanel);
            app.DistanceValueLabel.Text = '--m';
            app.ThermalCostResultLabel = uilabel(app.ResultsPanel);
            app.ThermalCostResultLabel.Text = 'THERMAL COST';
            app.ThermalCostValueLabel = uilabel(app.ResultsPanel);
            app.ThermalCostValueLabel.Text = '--';
            app.TotalCostResultabel = uilabel(app.ResultsPanel);
            app.TotalCostResultabel.Text = 'TOTAL COST ';
            app.TotalCostValueLabel = uilabel(app.ResultsPanel);
            app.TotalCostValueLabel.Text = '--';
            app.NodesResultLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.NodesResultLabel.Text = 'NODES EXPANDED';
            app.NodesValueLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.NodesValueLabel.Text = '--';
            app.TimeResultLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.TimeResultLabel.Text = 'PLANNING TIME';
            app.PlanningTimeValueLabel = uilabel(app.ThermalAwareAppUIFigure);
            app.PlanningTimeValueLabel.Text = '--s';
            % Show the figure after all components are created
            app.ThermalAwareAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_Aware_planner_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermalAwareAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermalAwareAppUIFigure)
        end
    end
end